classdef WagonMonitoring < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadDatabaseMenu             matlab.ui.container.Menu
        ResetMenu                    matlab.ui.container.Menu
        RebuildDatabaseMenu          matlab.ui.container.Menu
        CloseMenu                    matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        WagonStatusInformationPanel  matlab.ui.container.Panel
        SelectionMenuButtonGroup     matlab.ui.container.ButtonGroup
        LastKnownPositionButton      matlab.ui.control.ToggleButton
        ShowWagonStatusButton        matlab.ui.control.ToggleButton
        ShowTimetableButton          matlab.ui.control.ToggleButton
        SelectDateDatePicker         matlab.ui.control.DatePicker
        SelectDateDatePickerLabel    matlab.ui.control.Label
        WagonSelectionDropDown       matlab.ui.control.DropDown
        WagonSelectionLabel          matlab.ui.control.Label
        InformationTextArea          matlab.ui.control.TextArea
        InformationTextAreaLabel     matlab.ui.control.Label
        LoadDatabaseButton           matlab.ui.control.Button
        RebuildButton                matlab.ui.control.Button
        MovementAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MovingSummary   table   % database cached in memory
    end
    
    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            % At startup: only load/rebuild are active
            app.LoadDatabaseButton.Enable = 'on';
            app.RebuildButton.Enable = 'on';

            % Disable the rest
            % app.SelectionMenuButtonGroup.Enable = 'off';
            % app.WagonSelectionDropDown.Enable = 'off';
            % app.SelectDateDatePicker.Enable = 'off';
            app.WagonStatusInformationPanel.Enable = 'off';

            % Default info
            app.InformationTextArea.Value = "Please load or rebuild the database first.";
        end

        % Button pushed function: RebuildButton
        function RebuildButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, ...
                'Title','Please Wait', ...
                'Message','Rebuilding database...', ...
                'Indeterminate','on');
            drawnow;
            
            try
                app.MovingSummary = WagonDatabase();   % store result in property

                close(d);
                app.InformationTextArea.Value = "Database rebuilt successfully (" + ...
                    size(app.MovingSummary,1) + " records).";
                uniqueFolders = unique(app.MovingSummary.Folder);
                app.WagonSelectionDropDown.Items = cellstr(uniqueFolders);

            catch ME
                close(d);
                app.InformationTextArea.Value = "Error while rebuilding: " + ME.message;
            end
            app.WagonStatusInformationPanel.Enable = 'on';
        end

        % Button pushed function: LoadDatabaseButton
        function LoadDatabaseButtonPushed(app, event)
            dbFile = fullfile('D:\Kuliah\001_Thesis\Data Processing','MovingSummary.mat');
            if isfile(dbFile)
                S = load(dbFile, 'MovingSummary');
                app.MovingSummary = S.MovingSummary;

                % Enable other controls
                % app.SelectionMenuButtonGroup.Enable = 'on';
                % app.WagonSelectionDropDown.Enable = 'on';
                app.WagonStatusInformationPanel.Enable = 'on';

                % Fill DropDown with unique folders
                uniqueFolders = unique(app.MovingSummary.Folder);
                app.WagonSelectionDropDown.Items = cellstr(uniqueFolders);
                app.WagonSelectionDropDown.Value = app.WagonSelectionDropDown.Items{1};

                app.InformationTextArea.Value = "Database loaded (" + size(app.MovingSummary,1) + " records).";

                % Plot initial selection (since ShowTimetable is already toggled on)
                if app.SelectionMenuButtonGroup.SelectedObject == app.ShowTimetableButton
                    plotTimetables(app.MovementAxes, app.MovingSummary, app.WagonSelectionDropDown.Value);
                end
            else
                app.InformationTextArea.Value = "Error: MovingSummary.mat not found. Please rebuild first.";
            end
        end

        % Value changed function: WagonSelectionDropDown
        function WagonSelectionDropDownValueChanged(app, event)
            if isempty(app.MovingSummary)
                return;
            end
            selectedFolder = app.WagonSelectionDropDown.Value;

            if app.SelectionMenuButtonGroup.SelectedObject == app.ShowTimetableButton
                plotTimetables(app.MovementAxes, app.MovingSummary, selectedFolder);
                app.InformationTextArea.Value = "Plotted timetables for folder " + selectedFolder;
            end
        end

        % Selection changed function: SelectionMenuButtonGroup
        function SelectionMenuButtonGroupSelectionChanged(app, event)
            selectedBtn = app.SelectionMenuButtonGroup.SelectedObject;

            if selectedBtn == app.ShowTimetableButton
                if isempty(app.MovingSummary)
                    app.InfoTextArea.Value = "No database loaded.";
                    return;
                end
                plotTimetables(app.MovementAxes, app.MovingSummary);
                app.InformationTextArea.Value = "Timetable plotted.";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadDatabaseMenu = uimenu(app.FileMenu);
            app.LoadDatabaseMenu.Text = 'Load Database';
            app.ResetMenu = uimenu(app.FileMenu);
            app.ResetMenu.Text = 'Reset ';
            app.RebuildDatabaseMenu = uimenu(app.FileMenu);
            app.RebuildDatabaseMenu.Text = 'Rebuild Database';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.Text = 'Close';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MovementAxes = uiaxes(app.GridLayout);
            title(app.MovementAxes, 'Title')
            xlabel(app.MovementAxes, 'X')
            ylabel(app.MovementAxes, 'Y')
            zlabel(app.MovementAxes, 'Z')
            app.RebuildButton = uibutton(app.GridLayout, 'push');
            app.RebuildButton.ButtonPushedFcn = createCallbackFcn(app, @RebuildButtonPushed, true);
            app.RebuildButton.Text = 'Rebuild';
            app.LoadDatabaseButton = uibutton(app.GridLayout, 'push');
            app.LoadDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatabaseButtonPushed, true);
            app.LoadDatabaseButton.Text = {'Load '; 'Database'};
            app.InformationTextAreaLabel = uilabel(app.GridLayout);
            app.InformationTextAreaLabel.Text = 'Information';
            app.InformationTextArea = uitextarea(app.GridLayout);
            app.WagonStatusInformationPanel = uipanel(app.GridLayout);
            app.WagonStatusInformationPanel.Title = 'Wagon Status Information';
            app.WagonSelectionLabel = uilabel(app.WagonStatusInformationPanel);
            app.WagonSelectionLabel.Text = {'Wagon '; 'Selection'};
            app.WagonSelectionDropDown = uidropdown(app.WagonStatusInformationPanel);
            app.WagonSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @WagonSelectionDropDownValueChanged, true);
            app.SelectDateDatePickerLabel = uilabel(app.WagonStatusInformationPanel);
            app.SelectDateDatePickerLabel.Text = 'Select Date';
            app.SelectDateDatePicker = uidatepicker(app.WagonStatusInformationPanel);
            app.SelectionMenuButtonGroup = uibuttongroup(app.WagonStatusInformationPanel);
            app.SelectionMenuButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectionMenuButtonGroupSelectionChanged, true);
            app.SelectionMenuButtonGroup.Title = 'Selection Menu';
            app.ShowTimetableButton = uitogglebutton(app.SelectionMenuButtonGroup);
            app.ShowTimetableButton.Text = 'Show Timetable';
            app.ShowTimetableButton.Value = true;
            app.ShowWagonStatusButton = uitogglebutton(app.SelectionMenuButtonGroup);
            app.ShowWagonStatusButton.Text = {'Show Wagon'; 'Status'};
            app.LastKnownPositionButton = uitogglebutton(app.SelectionMenuButtonGroup);
            app.LastKnownPositionButton.Text = {'Last Known '; 'Position'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WagonMonitoring

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end